classdef projectfinalversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DataButton                  matlab.ui.control.Button
        DateEditFieldLabel          matlab.ui.control.Label
        DateEditField               matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        MapButton                   matlab.ui.control.Button
        TotalStrikes0Label          matlab.ui.control.Label
        TotalStrikes0Label_2        matlab.ui.control.Label
        TotalStrikes0Label_3        matlab.ui.control.Label
        TotalStrikesEditFieldLabel  matlab.ui.control.Label
        TotalStrikesEditField       matlab.ui.control.EditField
        DeathsEditFieldLabel        matlab.ui.control.Label
        DeathsEditField             matlab.ui.control.EditField
        CiviliansEditFieldLabel     matlab.ui.control.Label
        CiviliansEditField          matlab.ui.control.EditField
        SpeedinmSSpinnerLabel       matlab.ui.control.Label
        SpeedinmSSpinner            matlab.ui.control.Spinner
        CollectInfoButton           matlab.ui.control.Button
    end


    methods (Access = public)
    
        %Function that converts date as integer string to a nice date to print
        function [day, month, year] = getDate(app, date)
                [year, m] = strtok(date, '-');
                [m, day] = strtok(m(2:length(m)), '-');
                [day, ~] = strtok(day(2:length(day)), 'T');
                switch str2double(m)
                    case 1
                        month = 'January';
                    case 2
                        month = 'February';
                    case 3
                        month = 'March';
                    case 4
                        month = 'April';
                    case 5
                        month = 'May';
                    case 6
                        month = 'June';
                    case 7
                        month = 'July';
                    case 8
                        month = 'August';
                    case 9
                        month = 'September';
                    case 10
                        month = "October";
                    case 11
                        month = 'November';
                    case 12
                        month = 'December';
                end
            
            
        end
        
        %Gets number of strikes listed in the API
        function sizeValue = getSize(app)
            droneInformation = webread("https://api.dronestre.am/data");
            sizeArray = {};
            sizeArray = [sizeArray, droneInformation.strike.x_id];
            [~, sizeValue] = size(sizeArray);
            
        end
    end


    methods (Access = private)

        % Button pushed function: DataButton
        function DataButtonPushed(app, event)
            
            %Initial Variables / Value declaration
            droneInformation = webread("https://api.dronestre.am/data");
            elements = getSize(app);
            civlianCounter = 0;
            
            index = categorical({'Min','Max'});
            yP = [0 0];
            yY = [0 0];
            yS = [0 0];
            
            %Counter variables used later
            pStrikes = 0;
            yStrikes = 0;
            sStrikes = 0;
            
            %Initializes Bar graphs
            bar(app.UIAxes,index,yP);
            bar(app.UIAxes2,index,yY);
            bar(app.UIAxes3,index,yS);
              
            
            %Loop that goes through values and for 1: size of elements in API
            for i = 1:elements
                country = droneInformation.strike(i).country;
                minDeaths = droneInformation.strike(i).deaths_min;
                maxDeaths = droneInformation.strike(i).deaths_max;
                
                
                %Error checking to make sure the value entered was correct and a number, if not, we deal with it
                if minDeaths == '?'
                    minDeaths = 0;
                elseif isempty(minDeaths)
                    minDeaths = 0;
                else
                    minDeaths = str2double(droneInformation.strike(i).deaths_min);

                end
                if maxDeaths == '?'
                    maxDeaths = 0;
                elseif isempty(maxDeaths)
                    maxDeaths = 0;
                else
                    maxDeaths = str2double(droneInformation.strike(i).deaths_max);
                end
                
            
                %Check which country attack happened at, then edited data accordingly
                switch(country)
            
                    case "Pakistan"
                        %Increment number of strikes for that country
                        pStrikes = pStrikes + 1;
                        %Increment min deaths for plotting
                        yP(1) = yP(1) + minDeaths;
                        %Increment max deaths for plotting
                        yP(2) = yP(2) + maxDeaths;
                        %Update textbook
                        app.TotalStrikes0Label.Text = sprintf('Total Strikes: %d', pStrikes);
            
                    %Same as Pakistan    
                    case "Yemen"
                        yStrikes = yStrikes + 1;
                        yY(1) = yY(1) + minDeaths;
                        yY(2) = yY(2) + maxDeaths;
                        app.TotalStrikes0Label_2.Text = sprintf('Total Strikes: %d', yStrikes);
            
                    %Same as Pakistan
                    case "Somalia"
                        sStrikes = sStrikes + 1;
                        yS(1) = yS(1) + minDeaths;
                        yS(2) = yS(2) + maxDeaths;
                        app.TotalStrikes0Label_3.Text = sprintf('Total Strikes: %d', sStrikes);
                end


                date = droneInformation.strike(i).date;
                [day, month, year] = getDate(app, date);
                
                
                app.DateEditField.Value=(sprintf('%s %s, %s', month, day, year));
                
                %Pause based on your choice
                pause(app.SpeedinmSSpinner.Value * 0.001);
                
                %Update all bar graphs
                bar(app.UIAxes,index,yP,'k');
                bar(app.UIAxes2,index,yY,'k');
                bar(app.UIAxes3,index,yS,'k');
                hold(app.UIAxes);
                hold(app.UIAxes2);
                hold(app.UIAxes3);
                
                civText = droneInformation.strike(i).civilians;         
                
                %Checks to make sure there is nothing wrong with the value..
                %That we received for civilians. Lots of data entries that weren't
                %Numbers
                if isempty(civText)
                    civilians = 0;
                elseif length(civText) == 1
                    if civText ~= '?'
                        civilians = str2double(civText);
                    else
                        civilians = 0;
                    end
                else
                    if length(civText) < 6
                        if contains(civText, 'Some')
                            civilians = 0;
                        else
                            [civilians, ~] = strtok(civText, '-');
                            civilians = str2double(civilians);
                        end
                    else
                        civilians = 0;
                    end
                end
                
                
                %Increment civilian death total
                civlianCounter = civilians + civlianCounter;
                
                %Update data values
                app.TotalStrikesEditField.Value = sprintf('%d', i);
                app.DeathsEditField.Value = sprintf('%d - %d', yP(1) + yY(1) + yS(1), yP(2) + yS(2) + yY(2));
                app.CiviliansEditField.Value = sprintf('%d', civlianCounter);
                
            end
        end

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)
            droneInformation = webread("https://api.dronestre.am/data");
            
            elements = getSize(app);
            clf;
            
            %Sets up figure window 
            %Mapping Toolbox Add-on was used here
            worldmap({'Pakistan','Somalia', 'Yemen', 'Tanzania', 'India'})
            land = shaperead('landareas', 'UseGeoCoords', true);
            geoshow(land,'DefaultFaceColor','green','DefaultEdgeColor','black');
            
            %Goes through Elements and plots in figure window
            for i = 1:elements
                
                %Error checks data  to make plot size correspond
                minDeaths = droneInformation.strike(i).deaths_min;
                
                if minDeaths == '?'    
                    minDeaths = 0;
                elseif isempty(minDeaths)
                    minDeaths = 0;
                else
                    minDeaths = str2double(droneInformation.strike(i).deaths_min);

                end
                
                %Red dot means casualities
                %White means no deaths. Bigger dot means more deaths
                color = 'r';
                switch(minDeaths)
                    case 0
                        color = 'w';
                        number = 2;
                    case 1
                        number = 1;
                    case {2,3,4,5}
                        number = 2;
                    case {6,7,8,9}
                        number = 5;
                    otherwise
                        number = 7;
                end
                        

                %Plots point based on some other values
                geoshow(str2double(droneInformation.strike(i).lat), str2double(droneInformation.strike(i).lon), ...
                            'DisplayType', 'point', ...
                            'Marker', 'o', ...
                            'MarkerEdgeColor', 'k', ...
                            'MarkerFaceColor', color, ...
                            'MarkerSize', number)
                hold on;
                [day, month, year] = getDate(app, droneInformation.strike(i).date);
              %Updates and then pauses based on selected time
                app.DateEditField.Value=(sprintf('%s %s, %s', month, day, year));
                pause(app.SpeedinmSSpinner.Value * 0.001);
            end
   
        end

        % Button pushed function: CollectInfoButton
        function CollectInfoButtonPushed(app, event)
droneInformation = webread("https://api.dronestre.am/data");

getSize = {};
getSize = [getSize, droneInformation.strike.x_id];
[rows, elements] = size(getSize);

fid = fopen('strikeinfo.dat','w');

for i = 1:elements
    fprintf(fid,'%s. Location: %s, %s. %s deaths. %s.\n\n',droneInformation.strike(i).date,droneInformation.strike(i).location,droneInformation.strike(i).country,droneInformation.strike(i).deaths,droneInformation.strike(i).narrative);
end

fclose(fid);  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DataButton = uibutton(app.UIFigure, 'push');
            app.DataButton.ButtonPushedFcn = createCallbackFcn(app, @DataButtonPushed, true);
            app.DataButton.Text = 'Data';
            app.DateEditFieldLabel = uilabel(app.UIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pakistan')
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, 'Deaths')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Yemen')
            xlabel(app.UIAxes2, ' ')
            ylabel(app.UIAxes2, 'Deaths')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Somalia')
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, 'Deaths')
            app.MapButton = uibutton(app.UIFigure, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Text = 'Map';
            app.TotalStrikes0Label = uilabel(app.UIFigure);
            app.TotalStrikes0Label.Text = 'Total Strikes: 0';
            app.TotalStrikes0Label_2 = uilabel(app.UIFigure);
            app.TotalStrikes0Label_2.Text = 'Total Strikes: 0';
            app.TotalStrikes0Label_3 = uilabel(app.UIFigure);
            app.TotalStrikes0Label_3.Text = 'Total Strikes: 0';
            app.TotalStrikesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalStrikesEditFieldLabel.Text = 'Total Strikes';
            app.TotalStrikesEditField = uieditfield(app.UIFigure, 'text');
            app.DeathsEditFieldLabel = uilabel(app.UIFigure);
            app.DeathsEditFieldLabel.Text = 'Deaths';
            app.DeathsEditField = uieditfield(app.UIFigure, 'text');
            app.CiviliansEditFieldLabel = uilabel(app.UIFigure);
            app.CiviliansEditFieldLabel.Text = 'Civilians';
            app.CiviliansEditField = uieditfield(app.UIFigure, 'text');
            app.SpeedinmSSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedinmSSpinnerLabel.Text = 'Speed in mS';
            app.SpeedinmSSpinner = uispinner(app.UIFigure);
            app.SpeedinmSSpinner.Step = 10;
            app.SpeedinmSSpinner.Limits = [0 1000];
            app.SpeedinmSSpinner.Value = 100;
            app.CollectInfoButton = uibutton(app.UIFigure, 'push');
            app.CollectInfoButton.ButtonPushedFcn = createCallbackFcn(app, @CollectInfoButtonPushed, true);
            app.CollectInfoButton.Text = 'Collect Info';
        end
    end

    methods (Access = public)

        % Construct app
        function app = projectfinalversion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end